classdef GUI_Chebyshev_filter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        EXITButton               matlab.ui.control.Button
        OdozvanaHSButton         matlab.ui.control.Button
        NvrhparametrovebyevovhofiltraPanel  matlab.ui.container.Panel
        Rin50OhmLabel            matlab.ui.control.Label
        Rout50OhmLabel           matlab.ui.control.Label
        L3Label                  matlab.ui.control.Label
        C3Label                  matlab.ui.control.Label
        L2Label                  matlab.ui.control.Label
        C2Label                  matlab.ui.control.Label
        L1Label                  matlab.ui.control.Label
        C1Label                  matlab.ui.control.Label
        PrenosovfukciaPanel      matlab.ui.container.Panel
        Labelden                 matlab.ui.control.Label
        Labelnum                 matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        FsLabel                  matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        ChebyevfiltertypupsmovpriepustLabel  matlab.ui.control.Label
        NulyPolyPanel            matlab.ui.container.Panel
        Label16                  matlab.ui.control.Label
        Label15                  matlab.ui.control.Label
        Label14                  matlab.ui.control.Label
        Label9                   matlab.ui.control.Label
        Label8                   matlab.ui.control.Label
        Label7                   matlab.ui.control.Label
        Label5                   matlab.ui.control.Label
        Label4                   matlab.ui.control.Label
        Label3                   matlab.ui.control.Label
        NulyLabel                matlab.ui.control.Label
        PolyLabel                matlab.ui.control.Label
        ParametrePanel           matlab.ui.container.Panel
        RippleFactorButtonGroup  matlab.ui.container.ButtonGroup
        dBButton_3               matlab.ui.control.RadioButton
        dBButton_2               matlab.ui.control.RadioButton
        dBButton                 matlab.ui.control.RadioButton
        Label_2                  matlab.ui.control.Label
        DeltaEditField           matlab.ui.control.NumericEditField
        DeltaEditFieldLabel      matlab.ui.control.Label
        sLabel                   matlab.ui.control.Label
        radsLabel_2              matlab.ui.control.Label
        radsLabel                matlab.ui.control.Label
        HzLabel                  matlab.ui.control.Label
        wmaxEditField            matlab.ui.control.NumericEditField
        wmaxEditFieldLabel       matlab.ui.control.Label
        wminEditField            matlab.ui.control.NumericEditField
        wminEditFieldLabel       matlab.ui.control.Label
        TsimEditField            matlab.ui.control.NumericEditField
        TsimEditFieldLabel       matlab.ui.control.Label
        f_streduEditField        matlab.ui.control.NumericEditField
        f_streduLabel            matlab.ui.control.Label
        ZdraButton               matlab.ui.control.Button
        STARTSIMULATIONButton    matlab.ui.control.Button
        ObvodPanel               matlab.ui.container.Panel
        UIAxes3                  matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

properties (Access = private)
    DialogApp                   % Dialog box app
end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Labelnum.Text = ' '; % vymaze citatel TF
            app.Labelden.Text = ' '; % vymaze menovatel TF
            Tsim = app.TsimEditField.Value;
            f0 = app.f_streduEditField.Value;
            del = app.DeltaEditField.Value;
            del = del/100;

                    g1=3.3487; 
                    g2=0.7117; 
                    g3=3.3487;  
                    R=50;
                    %del=0.10;
                    %f0=2.5e6;
                    %L1x=35.47e-3; C1x=1.428e-6; L2x = 1.432; C2x = 35.38e-9; C3x=3.010e-6; L3x = 16.83e-3;Rsx=50; Rlx=50;
                    
                    L1 = (g1*R)/(2*pi*f0*del); 
                    L2 = (R*del)/(g2*2*pi*f0); 
                    L3 = (g3*R)/(2*pi*f0*del); 

                    C1 = del/(g1*R*2*pi*f0); 
                    C2 = g2/(R*2*pi*f0*del); 
                    C3 = del/(g3*R*2*pi*f0); 


                    num = [C1*C3*L2*R 0 0 0]
                    den = [C1*C2*C3*L1*L2*L3 C1*C2*C3*L1*L2*R+C1*C2*C3*L2*L3*R C1*C2*L1*L2+C1*C3*L1*L2+C1*C3*L1*L3+C1*C3*L2*L3+C2*C3*L2*L3+C1*C2*C3*L2*R*R C1*C3*L1*R+C1*C3*L2*R+C2*C3*L2*R+C1*C2*L2*R+C1*C3*L2*R+C1*C3*L3*R C1*L1+C1*L2+C2*L2+C3*L2+C3*L3+C1*C3*R*R C3*R+C1*R 1]

                    num=double(num);
                    den=double(den);
                    num=num/den(end);
                    den=den/den(end);
                    
                    app.Labelnum.Text = [num2str(num(1)),'s^3'];
                    app.Labelden.Text = [num2str(den(1)),'s^6 + ',num2str(den(2)),'s^5 + ',num2str(den(3)),'s^4 + ',num2str(den(4)),'s^3 + ',num2str(den(5)),'s^2 + ',num2str(den(6)),'^s + ',num2str(den(7))];
                    
                    app.C1Label.Text = ' ';
                    app.C2Label.Text = ' ';
                    app.C3Label.Text = ' ';
                    app.L1Label.Text = ' ';
                    app.L2Label.Text = ' ';
                    app.L3Label.Text = ' ';

                    app.C1Label.Text = sprintf('C1 = %.3e F',C1);
                    app.C2Label.Text = sprintf('C2 = %.3e F',C2);
                    app.C3Label.Text = sprintf('C3 = %.3e F',C3);
                    app.L1Label.Text = sprintf('L1 = %.3e H',L1);
                    app.L2Label.Text = sprintf('L2 = %.3e H',L2);
                    app.L3Label.Text = sprintf('L3 = %.3e H',L3);


                    zeros=zero(tf(num,den));
                    poles=pole(tf(num,den));
                      % p1=num2str(p1); 
                    format shortG
                    p1=round(poles(1)); p1 = p1/100000;p1=round(p1,3);
                    p1=num2str(p1); p1=sprintf('p1 = %s e5',p1);
                   
                    p2=round(poles(2)); p2 = p2/100000; p2=round(p2,3);
                    p2=num2str(p2); p2=sprintf('p2 = %s e5',p2);

                    p3=round(poles(3)); p3 = p3/100000;p3=round(p3,3);
                    p3=num2str(p3); p3=sprintf('p3 = %s e5',p3);

                    p4=round(poles(4)); p4 = p4/100000;p4=round(p4,3);
                    p4=num2str(p4); p4=sprintf('p4 = %s e5',p4);

                    p5=round(poles(5)); p5 = p5/100000;p5=round(p5,3);
                    p5=num2str(p5); p5=sprintf('p5 = %s e5',p5);

                    p6=round(poles(6)); p6 = p6/100000;p6=round(p6,3);
                    p6=num2str(p6); p6=sprintf('p6 = %s e5',p6);

                    n1=round(zeros(1)*100)/100; n1=num2str(n1); n1=sprintf('z1 = %s',n1); 
                    n2=round(zeros(2)*100)/100; n2=num2str(n2); n2=sprintf('z2 = %s',n2); 
                    n3=round(zeros(3)*100)/100; n3=num2str(n3); n3=sprintf('z3 = %s',n3); 
                    % zobrazenie vypocitanych polov
                    app.Label3.Text  = p1;
                    app.Label4.Text  = p2;
                    app.Label5.Text  = p3;
                    app.Label7.Text  = p4;
                    app.Label8.Text  = p5;
                    app.Label16.Text = p6;

                    app.Label9.Text  = n1;
                    app.Label14.Text = n2;
                    app.Label15.Text = n3;

                    G=tf(num,den);
                    t = linspace(0,Tsim);
                    y = step(G,t);
                    
                    plot(app.UIAxes,t,y,"LineWidth",3,'Color','r');
                    app.UIAxes.XLim = [0 Tsim];
                     % Graf pre bode
                    wmin = app.wminEditField.Value;
                    wmax = app.wmaxEditField.Value;
                    [mag,phase,wout] = bode(G,{wmin,wmax});
                    semilogx(app.UIAxes2,wout,20*log10(squeeze(mag)),"LineWidth",3,'Color','r');                                                                               
                    semilogx(app.UIAxes3,wout,squeeze(phase),"LineWidth",3,'Color','r');

        end

        % Button pushed function: STARTSIMULATIONButton
        function STARTSIMULATIONButtonPushed(app, event)

            app.Labelnum.Text = ' '; % vymaze citatel TF
            app.Labelden.Text = ' '; % vymaze menovatel TF

            wmin = app.wminEditField.Value;
            wmax = app.wmaxEditField.Value;

            Tsim = app.TsimEditField.Value;

            f0 = app.f_streduEditField.Value;
            del = app.DeltaEditField.Value;
            del = del/100;

            %del = 0.1;
            
           if (app.dBButton_3.Value == true) 
                        g1=3.3487; 
                        g2=0.7117; 
                        g3=3.3487;  
                        R=50;
           end
            if (app.dBButton_2.Value == true)    
                        g1=2.02359; 
                        g2=0.99410; 
                        g3=2.02359;  
                        R=50;
            end
             if (app.dBButton.Value == true) 
                        g1=1.22754; 
                        g2=1.15254; 
                        g3=1.22754;  
                        R=50;
            
            end
                    %g1=3.3487; 
                    %g2=0.7117; 
                    %g3=3.3487;  
                    %R=50;
                    %del=0.10;
                    %f0=2.5e6;
                    %L1x=35.47e-3; C1x=1.428e-6; L2x = 1.432; C2x = 35.38e-9; C3x=3.010e-6; L3x = 16.83e-3;Rsx=50; Rlx=50;
                    
                    L1 = (g1*R)/(2*pi*f0*del); 
                    L2 = (R*del)/(g2*2*pi*f0); 
                    L3 = (g3*R)/(2*pi*f0*del); 

                    C1 = del/(g1*R*2*pi*f0); 
                    C2 = g2/(R*2*pi*f0*del); 
                    C3 = del/(g3*R*2*pi*f0); 


                    num = [C1*C3*L2*R 0 0 0]
                    den = [C1*C2*C3*L1*L2*L3 C1*C2*C3*L1*L2*R+C1*C2*C3*L2*L3*R C1*C2*L1*L2+C1*C3*L1*L2+C1*C3*L1*L3+C1*C3*L2*L3+C2*C3*L2*L3+C1*C2*C3*L2*R*R C1*C3*L1*R+C1*C3*L2*R+C2*C3*L2*R+C1*C2*L2*R+C1*C3*L2*R+C1*C3*L3*R C1*L1+C1*L2+C2*L2+C3*L2+C3*L3+C1*C3*R*R C3*R+C1*R 1]

                    num=double(num);
                    den=double(den);
                    num=num/den(end);
                    den=den/den(end);
                    
                    app.Labelnum.Text = [num2str(num(1)),'s^3'];
                    app.Labelden.Text = [num2str(den(1)),'s^6 + ',num2str(den(2)),'s^5 + ',num2str(den(3)),'s^4 + ',num2str(den(4)),'s^3 + ',num2str(den(5)),'s^2 + ',num2str(den(6)),'^s + ',num2str(den(7))];
                    
                    app.C1Label.Text = ' ';
                    app.C2Label.Text = ' ';
                    app.C3Label.Text = ' ';
                    app.L1Label.Text = ' ';
                    app.L2Label.Text = ' ';
                    app.L3Label.Text = ' ';

                    app.C1Label.Text = sprintf('C1 = %.3e F',C1);
                    app.C2Label.Text = sprintf('C2 = %.3e F',C2);
                    app.C3Label.Text = sprintf('C3 = %.3e F',C3);
                    app.L1Label.Text = sprintf('L1 = %.3e H',L1);
                    app.L2Label.Text = sprintf('L2 = %.3e H',L2);
                    app.L3Label.Text = sprintf('L3 = %.3e H',L3);


                    zeros=zero(tf(num,den));
                    poles=pole(tf(num,den));
                      % p1=num2str(p1); 
                    %format shortG
                    p1=round(poles(1)); p1 = p1/100000;p1=round(p1,3);
                    p1=num2str(p1); p1=sprintf('p1 = %s e5',p1);
                   
                    p2=round(poles(2)); p2 = p2/100000; p2=round(p2,3);
                    p2=num2str(p2); p2=sprintf('p2 = %s e5',p2);

                    p3=round(poles(3)); p3 = p3/100000;p3=round(p3,3);
                    p3=num2str(p3); p3=sprintf('p3 = %s e5',p3);

                    p4=round(poles(4)); p4 = p4/100000;p4=round(p4,3);
                    p4=num2str(p4); p4=sprintf('p4 = %s e5',p4);

                    p5=round(poles(5)); p5 = p5/100000;p5=round(p5,3);
                    p5=num2str(p5); p5=sprintf('p5 = %s e5',p5);

                    p6=round(poles(6)); p6 = p6/100000;p6=round(p6,3);
                    p6=num2str(p6); p6=sprintf('p6 = %s e5',p6);

                    n1=round(zeros(1)*100)/100; 
                    n1=num2str(n1); n1=sprintf('z1 = %s',n1); 
                    n2=round(zeros(2)*100)/100; 
                    n2=num2str(n2); n2=sprintf('z2 = %s',n2); 
                    n3=round(zeros(3)*100)/100; 
                    n3=num2str(n3); n3=sprintf('z3 = %s',n3); 

                    % zobrazenie vypocitanych polov
                    app.Label3.Text  = p1;
                    app.Label4.Text  = p2;
                    app.Label5.Text  = p3;
                    app.Label7.Text  = p4;
                    app.Label8.Text  = p5;
                    app.Label16.Text = p6;

                    app.Label9.Text  = n1;
                    app.Label14.Text = n2;
                    app.Label15.Text = n3;


                    G=tf(num,den);
                    t = linspace(0,Tsim);
                    y = step(G,t);
                    a=rand;
                    b=rand;
                    c=rand;

                    plot(app.UIAxes,t,y,"LineWidth",3,'Color',[a,b,c]);
                    app.UIAxes.XLim = [0 Tsim];
                     % Graf pre bode
            
                    [mag,phase,wout] = bode(G,{wmin,wmax});
                    semilogx(app.UIAxes2,wout,20*log10(squeeze(mag)),"LineWidth",3,'Color',[a,b,c]);                                                                               
                    semilogx(app.UIAxes3,wout,squeeze(phase),"LineWidth",3,'Color',[a,b,c]);

       
        end

        % Value changed function: f_streduEditField
        function f_streduEditFieldValueChanged(app, event)
            value = app.f_streduEditField.Value;
        end

        % Value changed function: DeltaEditField
        function DeltaEditFieldValueChanged(app, event)
            value = app.DeltaEditField.Value;          
        end

        % Selection changed function: RippleFactorButtonGroup
        function RippleFactorButtonGroupSelectionChanged(app, event)
            selectedButton = app.RippleFactorButtonGroup.SelectedObject;
        end

        % Button pushed function: OdozvanaHSButton
        function OdozvanaHSButtonPushed(app, event)
            app.DialogApp = Odozva_na_HS(app,app.f_streduEditField.Value,app.DeltaEditField.Value);
        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            delete(app)
        end

        % Callback function
        function ZdraButtonChanged(app, event)
           
        end

        % Button pushed function: ZdraButton
        function ZdraButtonPushed(app, event)
           if (app.ZdraButton.Text == 'Zdržať')
               hold(app.UIAxes,"on")
               hold(app.UIAxes2,"on")
               hold(app.UIAxes3,"on")
               app.ZdraButton.Text = 'Pustiť';
            elseif(app.ZdraButton.Text == 'Pustiť')
               hold(app.UIAxes,"off")
               hold(app.UIAxes2,"off")
               hold(app.UIAxes3,"off")
               app.ZdraButton.Text = 'Zdržať';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Prechodová charakteristika')
            xlabel(app.UIAxes, '\rightarrow t[s]')
            ylabel(app.UIAxes, '\rightarrow U_{RL}/U_{0}')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Frekvenčna charakteristika')
            xlabel(app.UIAxes2, '\rightarrow \omega [rad/s]')
            ylabel(app.UIAxes2, '\rightarrow U_{RL}(\omega)/U_{0}(\omega) [dB] ')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes3, '\rightarrow \omega [rad/s]')
            ylabel(app.UIAxes3, '\rightarrow \phi [deg]')
            zlabel(app.UIAxes3, 'Z')
            app.ObvodPanel = uipanel(app.UIFigure);
            app.ObvodPanel.Title = 'Obvod';
            app.STARTSIMULATIONButton = uibutton(app.UIFigure, 'push');
            app.STARTSIMULATIONButton.ButtonPushedFcn = createCallbackFcn(app, @STARTSIMULATIONButtonPushed, true);
            app.STARTSIMULATIONButton.Text = {'START'; 'SIMULATION'};
            app.ZdraButton = uibutton(app.UIFigure, 'push');
            app.ZdraButton.ButtonPushedFcn = createCallbackFcn(app, @ZdraButtonPushed, true);
            app.ZdraButton.Text = 'Zdržať';
            app.ParametrePanel = uipanel(app.UIFigure);
            app.ParametrePanel.Title = 'Parametre';
            app.f_streduLabel = uilabel(app.ParametrePanel);
            app.f_streduLabel.Text = 'f_stredu';
            app.f_streduEditField = uieditfield(app.ParametrePanel, 'numeric');
            app.f_streduEditField.ValueChangedFcn = createCallbackFcn(app, @f_streduEditFieldValueChanged, true);
            app.f_streduEditField.Value = 2500000;
            app.TsimEditFieldLabel = uilabel(app.ParametrePanel);
            app.TsimEditFieldLabel.Text = 'Tsim';
            app.TsimEditField = uieditfield(app.ParametrePanel, 'numeric');
            app.TsimEditField.Value = 3e-05;
            app.wminEditFieldLabel = uilabel(app.ParametrePanel);
            app.wminEditFieldLabel.Text = 'wmin';
            app.wminEditField = uieditfield(app.ParametrePanel, 'numeric');
            app.wminEditField.Value = 14000000;
            app.wmaxEditFieldLabel = uilabel(app.ParametrePanel);
            app.wmaxEditFieldLabel.Text = 'wmax';
            app.wmaxEditField = uieditfield(app.ParametrePanel, 'numeric');
            app.wmaxEditField.Value = 17500000;
            app.HzLabel = uilabel(app.ParametrePanel);
            app.HzLabel.Text = '(Hz)';
            app.radsLabel = uilabel(app.ParametrePanel);
            app.radsLabel.Text = {'rad/s'; ''};
            app.radsLabel_2 = uilabel(app.ParametrePanel);
            app.radsLabel_2.Text = 'rad/s';
            app.sLabel = uilabel(app.ParametrePanel);
            app.sLabel.Text = 's';
            app.DeltaEditFieldLabel = uilabel(app.ParametrePanel);
            app.DeltaEditFieldLabel.Text = 'Delta';
            app.DeltaEditField = uieditfield(app.ParametrePanel, 'numeric');
            app.DeltaEditField.ValueChangedFcn = createCallbackFcn(app, @DeltaEditFieldValueChanged, true);
            app.DeltaEditField.Value = 10;
            app.Label_2 = uilabel(app.ParametrePanel);
            app.Label_2.Text = '(%)';
            app.RippleFactorButtonGroup = uibuttongroup(app.ParametrePanel);
            app.RippleFactorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RippleFactorButtonGroupSelectionChanged, true);
            app.RippleFactorButtonGroup.Title = 'Ripple Factor';
            app.dBButton = uiradiobutton(app.RippleFactorButtonGroup);
            app.dBButton.Text = '0,2 dB';
            app.dBButton_2 = uiradiobutton(app.RippleFactorButtonGroup);
            app.dBButton_2.Text = '1 dB';
            app.dBButton_3 = uiradiobutton(app.RippleFactorButtonGroup);
            app.dBButton_3.Text = '3 dB';
            app.dBButton_3.Value = true;
            app.NulyPolyPanel = uipanel(app.UIFigure);
            app.NulyPolyPanel.Title = 'Nuly & Poly';
            app.PolyLabel = uilabel(app.NulyPolyPanel);
            app.PolyLabel.Text = 'Poly:';
            app.NulyLabel = uilabel(app.NulyPolyPanel);
            app.NulyLabel.Text = 'Nuly:';
            app.Label3 = uilabel(app.NulyPolyPanel);
            app.Label3.Text = 'Label3';
            app.Label4 = uilabel(app.NulyPolyPanel);
            app.Label4.Text = 'Label4';
            app.Label5 = uilabel(app.NulyPolyPanel);
            app.Label5.Text = 'Label5';
            app.Label7 = uilabel(app.NulyPolyPanel);
            app.Label7.Text = 'Label7';
            app.Label8 = uilabel(app.NulyPolyPanel);
            app.Label8.Text = 'Label8';
            app.Label9 = uilabel(app.NulyPolyPanel);
            app.Label9.Text = 'Label9';
            app.Label14 = uilabel(app.NulyPolyPanel);
            app.Label14.Text = 'Label14';
            app.Label15 = uilabel(app.NulyPolyPanel);
            app.Label15.Text = 'Label15';
            app.Label16 = uilabel(app.NulyPolyPanel);
            app.Label16.Text = 'Label16';
            app.ChebyevfiltertypupsmovpriepustLabel = uilabel(app.UIFigure);
            app.ChebyevfiltertypupsmovpriepustLabel.Text = 'C﻿hebyšev filter typu pásmový priepust';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'NS.png');
            app.PrenosovfukciaPanel = uipanel(app.UIFigure);
            app.PrenosovfukciaPanel.Title = 'Prenosová fukcia';
            app.FsLabel = uilabel(app.PrenosovfukciaPanel);
            app.FsLabel.Text = 'F(s) =';
            app.Label = uilabel(app.PrenosovfukciaPanel);
            app.Label.Text = '---------------------------------------------------------------------------------------------------------------------------------------';
            app.Labelnum = uilabel(app.PrenosovfukciaPanel);
            app.Labelnum.Text = 'Labelnum';
            app.Labelden = uilabel(app.PrenosovfukciaPanel);
            app.Labelden.Text = 'Labelden';
            app.NvrhparametrovebyevovhofiltraPanel = uipanel(app.UIFigure);
            app.NvrhparametrovebyevovhofiltraPanel.Title = 'Návrh parametrov Čebyševovho filtra';
            app.C1Label = uilabel(app.NvrhparametrovebyevovhofiltraPanel);
            app.C1Label.Text = 'C1';
            app.L1Label = uilabel(app.NvrhparametrovebyevovhofiltraPanel);
            app.L1Label.Text = 'L1';
            app.C2Label = uilabel(app.NvrhparametrovebyevovhofiltraPanel);
            app.C2Label.Text = 'C2';
            app.L2Label = uilabel(app.NvrhparametrovebyevovhofiltraPanel);
            app.L2Label.Text = 'L2';
            app.C3Label = uilabel(app.NvrhparametrovebyevovhofiltraPanel);
            app.C3Label.Text = 'C3';
            app.L3Label = uilabel(app.NvrhparametrovebyevovhofiltraPanel);
            app.L3Label.Text = 'L3';
            app.Rout50OhmLabel = uilabel(app.NvrhparametrovebyevovhofiltraPanel);
            app.Rout50OhmLabel.Text = 'Rout = 50 (Ohm)';
            app.Rin50OhmLabel = uilabel(app.NvrhparametrovebyevovhofiltraPanel);
            app.Rin50OhmLabel.Text = 'Rin = 50 (Ohm)';
            app.OdozvanaHSButton = uibutton(app.UIFigure, 'push');
            app.OdozvanaHSButton.ButtonPushedFcn = createCallbackFcn(app, @OdozvanaHSButtonPushed, true);
            app.OdozvanaHSButton.Text = 'Odozva na HS';
            app.EXITButton = uibutton(app.UIFigure, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Chebyshev_filter

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end