classdef Odozva_na_HS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        assSlider                  matlab.ui.control.Slider
        assSliderLabel             matlab.ui.control.Label
        ParametrePanel             matlab.ui.container.Panel
        DeltEditField              matlab.ui.control.NumericEditField
        DeltEditFieldLabel         matlab.ui.control.Label
        FstrEditField              matlab.ui.control.NumericEditField
        FstrEditFieldLabel         matlab.ui.control.Label
        RipplefactorButtonGroup    matlab.ui.container.ButtonGroup
        dBButton_3                 matlab.ui.control.RadioButton
        dBButton_2                 matlab.ui.control.RadioButton
        dBButton                   matlab.ui.control.RadioButton
        EXITButton                 matlab.ui.control.Button
        FrekvenciaradsSlider       matlab.ui.control.Slider
        FrekvenciaradsSliderLabel  matlab.ui.control.Label
        STARTButton                matlab.ui.control.Button
        OdozvasystmunaharmonickbudiacisignlLabel  matlab.ui.control.Label
        ZmenafrekvencievstupnhosignluPanel  matlab.ui.container.Panel
        radsLabel                  matlab.ui.control.Label
        wEditField                 matlab.ui.control.NumericEditField
        wEditFieldLabel            matlab.ui.control.Label
        UIAxes4                    matlab.ui.control.UIAxes
    end

    properties (Access = private) 
        CallingApp   % Main app object
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, freq, delt)
            app.CallingApp = mainapp;
            app.FstrEditField.Value = freq;
            app.DeltEditField.Value = delt;
           % app.assSlider.Value = app.wEditField.Value;
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
          
                        f0 = app.FstrEditField.Value;
                        del = app.DeltEditField.Value;
                        del = del/100;
                        app.wEditField.Value = app.FrekvenciaradsSlider.Value;
                        w=app.FrekvenciaradsSlider.Value;
                        
                        if (app.dBButton_3.Value == true) 
                                    g1=3.3487; 
                                    g2=0.7117; 
                                    g3=3.3487;  
                                    R=50;
                        end
                        if (app.dBButton_2.Value == true)    
                                    g1=2.02359; 
                                    g2=0.99410; 
                                    g3=2.02359;  
                                    R=50;
                        end
                         if (app.dBButton.Value == true) 
                                    g1=1.22754; 
                                    g2=1.15254; 
                                    g3=1.22754;  
                                    R=50;
                        
                        end
                                    L1 = (g1*R)/(2*pi*f0*del); 
                                    L2 = (R*del)/(g2*2*pi*f0); 
                                    L3 = (g3*R)/(2*pi*f0*del); 
                
                                    C1 = del/(g1*R*2*pi*f0); 
                                    C2 = g2/(R*2*pi*f0*del); 
                                    C3 = del/(g3*R*2*pi*f0); 
                       

                        num = [C1*C3*L2*R 0 0 0]
                        den = [C1*C2*C3*L1*L2*L3 C1*C2*C3*L1*L2*R+C1*C2*C3*L2*L3*R C1*C2*L1*L2+C1*C3*L1*L2+C1*C3*L1*L3+C1*C3*L2*L3+C2*C3*L2*L3+C1*C2*C3*L2*R*R C1*C3*L1*R+C1*C3*L2*R+C2*C3*L2*R+C1*C2*L2*R+C1*C3*L2*R+C1*C3*L3*R C1*L1+C1*L2+C2*L2+C3*L2+C3*L3+C1*C3*R*R C3*R+C1*R 1]

                        num=double(num);
                        den=double(den);
                        num=num/den(end);
                        den=den/den(end);

                        
                        G=tf(num,den);
                        G=G*2.85;
                        n=50;
                        T1=2*pi/w; Tkon1=n*T1;      % volba frekv, doba periody, doba kon.
                        [u,t1]=gensig('sine',T1,Tkon1,T1/24); % doba periódy, trvanie signálu, vzorkovanie
                        y=lsim (G,u,t1);
                        
                        T = app.assSlider.Value;
                        app.UIAxes4.XLim = [1.79e-5 T];
                        p=plot(app.UIAxes4,t1,y,t1,u)
                        p(2).LineWidth = 1;
                        p(2).LineStyle = '--';
                        p(2).Color = 'r';
                        p(1).LineWidth = 3;
                        legend(app.UIAxes4,{'Výstupný signál','Vstupný signál'},'Location','southeast');
                        

        end

        % Value changed function: FstrEditField
        function FstrEditFieldValueChanged(app, event)
            value = app.FstrEditField.Value;
            
        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
             delete(app);
        end

        % Value changing function: FrekvenciaradsSlider
        function FrekvenciaradsSliderValueChanging(app, event)
            changingValue = event.Value;
            app.wEditField.Value = app.FrekvenciaradsSlider.Value;
        end

        % Value changed function: wEditField
        function wEditFieldValueChanged(app, event)
            value = app.wEditField.Value;
            app.FrekvenciaradsSlider.Value=app.wEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes4 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes4, '\rightarrow t[s]')
            ylabel(app.UIAxes4, '\rightarrow A [dB]')
            zlabel(app.UIAxes4, 'Z')
            app.ZmenafrekvencievstupnhosignluPanel = uipanel(app.UIFigure);
            app.ZmenafrekvencievstupnhosignluPanel.Title = 'Zmena frekvencie vstupného signálu';
            app.wEditFieldLabel = uilabel(app.ZmenafrekvencievstupnhosignluPanel);
            app.wEditFieldLabel.Text = 'w';
            app.wEditField = uieditfield(app.ZmenafrekvencievstupnhosignluPanel, 'numeric');
            app.wEditField.ValueChangedFcn = createCallbackFcn(app, @wEditFieldValueChanged, true);
            app.wEditField.Value = 14500000;
            app.radsLabel = uilabel(app.ZmenafrekvencievstupnhosignluPanel);
            app.radsLabel.Text = 'rad/s';
            app.OdozvasystmunaharmonickbudiacisignlLabel = uilabel(app.UIFigure);
            app.OdozvasystmunaharmonickbudiacisignlLabel.Text = 'Odozva systému na harmonický budiaci signál';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.FrekvenciaradsSliderLabel = uilabel(app.UIFigure);
            app.FrekvenciaradsSliderLabel.Text = 'Frekvencia (rad/s)';
            app.FrekvenciaradsSlider = uislider(app.UIFigure);
            app.FrekvenciaradsSlider.Limits = [5000000 20000000];
            app.FrekvenciaradsSlider.ValueChangingFcn = createCallbackFcn(app, @FrekvenciaradsSliderValueChanging, true);
            app.FrekvenciaradsSlider.Value = 14500000;
            app.EXITButton = uibutton(app.UIFigure, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            app.RipplefactorButtonGroup = uibuttongroup(app.UIFigure);
            app.RipplefactorButtonGroup.Title = 'Ripple factor';
            app.dBButton = uiradiobutton(app.RipplefactorButtonGroup);
            app.dBButton.Text = '0.2 dB';
            app.dBButton_2 = uiradiobutton(app.RipplefactorButtonGroup);
            app.dBButton_2.Text = '1 dB';
            app.dBButton_3 = uiradiobutton(app.RipplefactorButtonGroup);
            app.dBButton_3.Text = '3 dB';
            app.dBButton_3.Value = true;
            app.ParametrePanel = uipanel(app.UIFigure);
            app.ParametrePanel.Title = 'Parametre';
            app.FstrEditFieldLabel = uilabel(app.ParametrePanel);
            app.FstrEditFieldLabel.Text = 'F str';
            app.FstrEditField = uieditfield(app.ParametrePanel, 'numeric');
            app.FstrEditField.ValueChangedFcn = createCallbackFcn(app, @FstrEditFieldValueChanged, true);
            app.DeltEditFieldLabel = uilabel(app.ParametrePanel);
            app.DeltEditFieldLabel.Text = 'Delt';
            app.DeltEditField = uieditfield(app.ParametrePanel, 'numeric');
            app.assSliderLabel = uilabel(app.UIFigure);
            app.assSliderLabel.Text = 'čas(s)';
            app.assSlider = uislider(app.UIFigure);
            app.assSlider.Limits = [1.8e-05 1.94e-05];
            app.assSlider.Value = 1.8e-05;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Odozva_na_HS(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end